classdef colordle < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure         matlab.ui.Figure
    Stats            matlab.ui.control.Button
    NewGame          matlab.ui.control.Button
    GuessthehexadecimalcodeforLabel  matlab.ui.control.Label
    ColorSample      matlab.ui.control.Button
    Version010Label  matlab.ui.control.Label
    TheHexCode       matlab.ui.control.Label
    Delete           matlab.ui.control.Button
    Enter            matlab.ui.control.Button
    KF               matlab.ui.control.Button
    KE               matlab.ui.control.Button
    KD               matlab.ui.control.Button
    KC               matlab.ui.control.Button
    KB               matlab.ui.control.Button
    KA               matlab.ui.control.Button
    K9               matlab.ui.control.Button
    K8               matlab.ui.control.Button
    K7               matlab.ui.control.Button
    K6               matlab.ui.control.Button
    K5               matlab.ui.control.Button
    K4               matlab.ui.control.Button
    K3               matlab.ui.control.Button
    K2               matlab.ui.control.Button
    K1               matlab.ui.control.Button
    K0               matlab.ui.control.Button
    C6_6             matlab.ui.control.EditField
    C6_5             matlab.ui.control.EditField
    C6_4             matlab.ui.control.EditField
    C6_3             matlab.ui.control.EditField
    C6_2             matlab.ui.control.EditField
    C6_1             matlab.ui.control.EditField
    C5_6             matlab.ui.control.EditField
    C5_5             matlab.ui.control.EditField
    C5_4             matlab.ui.control.EditField
    C5_3             matlab.ui.control.EditField
    C5_2             matlab.ui.control.EditField
    C5_1             matlab.ui.control.EditField
    C4_6             matlab.ui.control.EditField
    C4_5             matlab.ui.control.EditField
    C4_4             matlab.ui.control.EditField
    C4_3             matlab.ui.control.EditField
    C4_2             matlab.ui.control.EditField
    C4_1             matlab.ui.control.EditField
    C3_6             matlab.ui.control.EditField
    C3_5             matlab.ui.control.EditField
    C3_4             matlab.ui.control.EditField
    C3_3             matlab.ui.control.EditField
    C3_2             matlab.ui.control.EditField
    C3_1             matlab.ui.control.EditField
    C2_6             matlab.ui.control.EditField
    C2_5             matlab.ui.control.EditField
    C2_4             matlab.ui.control.EditField
    C2_3             matlab.ui.control.EditField
    C2_2             matlab.ui.control.EditField
    C2_1             matlab.ui.control.EditField
    C1_6             matlab.ui.control.EditField
    C1_5             matlab.ui.control.EditField
    C1_4             matlab.ui.control.EditField
    C1_3             matlab.ui.control.EditField
    C1_2             matlab.ui.control.EditField
    C1_1             matlab.ui.control.EditField
    Help             matlab.ui.control.Button
    ColordleLabel    matlab.ui.control.Label
  end

  
  properties (Access = private)
    hexCode % The hexadecimal for the game
    textTileNames % Names of the text tiles
    textTilePosition % Index position of the current text tile
    textTileRow % Index of text tile row
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      % Define hexadecimal code
      app.hexCode = string(dec2hex(randi([0, 2^24-1], 1), 6));
      app.TheHexCode.Text = app.hexCode;
      app.ColorSample.BackgroundColor = "#"+app.hexCode;
      
      % Define text tile names
      app.textTileNames = ["C1_1";"C1_2";"C1_3";"C1_4";"C1_5";"C1_6";...
                       "C2_1";"C2_2";"C2_3";"C2_4";"C2_5";"C2_6";...
                       "C3_1";"C3_2";"C3_3";"C3_4";"C3_5";"C3_6";...
                       "C4_1";"C4_2";"C4_3";"C4_4";"C4_5";"C4_6";...
                       "C5_1";"C5_2";"C5_3";"C5_4";"C5_5";"C5_6";...
                       "C6_1";"C6_2";"C6_3";"C6_4";"C6_5";"C6_6"];

      % Define text tile position
      app.textTilePosition = 1;

      % Define text tile row
      app.textTileRow = 1;
      
      % Turn off editable property for all text tiles and disable rows 2-6
      buttonFirst = string((1:6)');
      buttonLast = string((1:6)');
      buttonC = "C";
      button_ = "_";
      for f = 1:length(buttonFirst)
      for l = 1:length(buttonLast)
        app.(buttonC+buttonFirst(f)+button_+buttonLast(l)).Editable = "off";
        if f >= 2
         app.(buttonC+buttonFirst(f)+button_+buttonLast(l)).Enable = "off";
        end
      end
      end
      
    end

    % Button pushed function: Help
    function HelpButtonPushed(app, event)
      msg = "HOW TO PLAY: Colordle is a lot like Wordle, but with a few differences. The main difference is that you need to guess a 6-character hexadecimal code instead of a 5-character word. The hexadecimal alphabet only has 16 characters, but unlike Wordle, every combination of characters is possible. Secondly, Colordle does not color tiles in the same way as Wordle. Tiles do not turn yellow if you guess a character correctly but in the wrong tile position. Instead, tiles will only turn yellow if your guess is within +/-3 of the true character in the same tile position. However, tiles still turn green if you guess a character in the correct position.";
      msgbox(msg)
    end

    % Button pushed function: Stats
    function StatsButtonPushed(app, event)
        fileName = 'colordle_statistics.txt';
        if ispc
          colordleFolder = fullfile(getenv('USERPROFILE'),'Desktop');
        elseif ismac || isunix
          colordleFolder = fullfile(getenv('HOME'));
        end
        filePath = fullfile(colordleFolder,fileName);
        if ~isfile(filePath)
          writematrix([],filePath);
        end
        % Display stats
        stats = readtable(filePath,'ReadVariableNames',false,'FileType','text');
        n0 = sum(stats.Var1==0);
        n1 = sum(stats.Var1==1);
        n2 = sum(stats.Var1==2);
        n3 = sum(stats.Var1==3);
        n4 = sum(stats.Var1==4);
        n5 = sum(stats.Var1==5);
        n6 = sum(stats.Var1==6);
        ns = [n0 n1 n2 n3 n4 n5 n6];
        varNames = {'DNF','1','2','3','4','5','6'};
        figure('units','inches','position',[6 3 4 3])
        barh(ns,'FaceColor','#05ac2f')
        axis tight
        yticklabels(varNames)
        xlabel('Guess Distribution')
        set(gca,'YDir','reverse',...
            'TickDir','none',...
            'Color','#282A36',...
            'XColor','#F8F8F2',...
            'YColor','#F8F8F2')
        set(gcf,'Color','#282A36')
        box on
        title('Colordle Stats','Color','#F8F8F2')

    end

    % Button pushed function: NewGame
    function NewGameButtonPushed(app, event)
      userSelection = questdlg('Are you sure you want to start a new game?',...
        'NEW GAME',...
        'OK','Cancel','Cancel');
      if isempty(userSelection)
        % User clicked 'X' instead of a button
        return
      end
      switch userSelection
        case 'OK'
          colordle;
          close(app.UIFigure)
        case 'Cancel'
          return
      end
    end

    % Button pushed function: Delete
    function DeleteButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tyle names
      tileNames = app.textTileNames;

      if mod(pos,6) == 1
        % Get current tile and delete tile text
        currentTile = tileNames(pos);
        app.(currentTile).Value = "";
        return
      end

      if mod(pos,6) == 0
        currentTile = tileNames(pos);
        if strcmp(app.(currentTile).Value,"")
          % Get the previous tile
          previousTile = tileNames(pos - 1);
          % Delete the current tile text
          app.(previousTile).Value = "";
          % Update tile
          app.textTilePosition = pos - 1;
          return
        else
          % Delete current tile text
          app.(currentTile).Value = "";
          return
        end
      end

      if mod(pos,6) ~= 1
        % Get the previous tile
        previousTile = tileNames(pos-1);
        % Delete current tile text
        app.(previousTile).Value = "";
        % Update tile
        app.textTilePosition = pos - 1;
      end
    end

    % Button pushed function: Enter
    function EnterButtonPushed(app, event)
      %
      % Check current row and tile position
      %
      currentRow = app.textTileRow;
      currentPos = app.textTilePosition;
      
      %
      % Ensure that all values in row have been filled
      %
      C = "C";
      c_ = "_";
      for i = 1:6
        if strcmp(app.(C+string(currentRow)+c_+string(i)).Value,"")
          return
        end
      end

      %
      % End game is color is guessed correctly
      %
      % Define the user color
      c1 = app.("C"+string(currentRow)+"_1").Value;
      c2 = app.("C"+string(currentRow)+"_2").Value;
      c3 = app.("C"+string(currentRow)+"_3").Value;
      c4 = app.("C"+string(currentRow)+"_4").Value;
      c5 = app.("C"+string(currentRow)+"_5").Value;
      c6 = app.("C"+string(currentRow)+"_6").Value;
      userColor = [c1 c2 c3 c4 c5 c6];
      % Define the true color
      trueColor = char(app.hexCode);
      % Disable all buttons if the user color matches the true color, and
      % change the tile colors to green
      if strcmp(trueColor,userColor)
        % Color tiles green
          app.("C"+string(currentRow)+"_1").BackgroundColor = '#05ac2f';
          app.("C"+string(currentRow)+"_2").BackgroundColor = '#05ac2f';
          app.("C"+string(currentRow)+"_3").BackgroundColor = '#05ac2f';
          app.("C"+string(currentRow)+"_4").BackgroundColor = '#05ac2f';
          app.("C"+string(currentRow)+"_5").BackgroundColor = '#05ac2f';
          app.("C"+string(currentRow)+"_6").BackgroundColor = '#05ac2f';
        % Disable Buttons
          app.Delete.Enable = 'off';
          app.Enter.Enable = 'off';
          app.K0.Enable = 'off';
          app.K1.Enable = 'off';
          app.K2.Enable = 'off';
          app.K3.Enable = 'off';
          app.K4.Enable = 'off';
          app.K5.Enable = 'off';
          app.K6.Enable = 'off';
          app.K7.Enable = 'off';
          app.K8.Enable = 'off';
          app.K9.Enable = 'off';
          app.KA.Enable = 'off';
          app.KB.Enable = 'off';
          app.KC.Enable = 'off';
          app.KD.Enable = 'off';
          app.KE.Enable = 'off';
          app.KF.Enable = 'off';
          msgbox('You did it! (Close to view grid)')
        % Update statistics
        fileName = 'colordle_statistics.txt';
        if ispc
          colordleFolder = fullfile(getenv('USERPROFILE'),'Desktop');
        elseif ismac || isunix
          colordleFolder = fullfile(getenv('HOME'));
        end
        filePath = fullfile(colordleFolder,fileName);
        if isfile(filePath)
          fileID = fopen(filePath, 'a');
        else
          writematrix([],filePath);
          fileID = fopen(filePath,'a');
        end
        fprintf(fileID,'%d\n',currentRow);
        fclose(fileID);
        % End main function
        return
      end
      
      %
      % Loop through tiles and check whether they match the true value
      %
      %   Recall that "userColor" is a char-vector of the user's guess
      %   and that "trueColor" is a char-vector of the actual color
      %
      for tile = 1:6
        % Get the tile values (both user and actual)
        u = userColor(tile); % The user's guess for the tile position
        t = trueColor(tile); % The true value of the tile position
        % Check for matches
        if strcmp(t,u)
          % Perfect match -- color the tile green
          app.("C"+string(currentRow)+"_"+string(tile)).BackgroundColor = '#05ac2f';
        else
          % Not perfect match -- check if user tile is within 3 of the true
          % value -- if so, color the tile yellow
          if checkMatch(u,t)
            app.("C"+string(currentRow)+"_"+string(tile)).BackgroundColor = '#f6bc07';
          end
        end
        
      end

      %
      % Update current row and position
      %
      if currentRow < 6
        app.textTileRow = currentRow + 1;
        app.textTilePosition = currentPos + 1;
      elseif currentRow == 6
        trueColor = char(app.hexCode);
        msgbox(['Too bad! The correct color was ' trueColor])
        % Disable all buttons
          app.Delete.Enable = 'off';
          app.Enter.Enable = 'off';
          app.K0.Enable = 'off';
          app.K1.Enable = 'off';
          app.K2.Enable = 'off';
          app.K3.Enable = 'off';
          app.K4.Enable = 'off';
          app.K5.Enable = 'off';
          app.K6.Enable = 'off';
          app.K7.Enable = 'off';
          app.K8.Enable = 'off';
          app.K9.Enable = 'off';
          app.KA.Enable = 'off';
          app.KB.Enable = 'off';
          app.KC.Enable = 'off';
          app.KD.Enable = 'off';
          app.KE.Enable = 'off';
          app.KF.Enable = 'off';
        % Update stats
        fileName = 'colordle_statistics.txt';
        if ispc
          colordleFolder = fullfile(getenv('USERPROFILE'),'Desktop');
        elseif ismac || isunix
          colordleFolder = fullfile(getenv('HOME'));
        end
        filePath = fullfile(colordleFolder,fileName);
        if isfile(filePath)
          fileID = fopen(filePath, 'a');
        else
          writematrix([],filePath);
          fileID = fopen(filePath,'a');
        end
        fprintf(fileID,'%d\n',0);
        fclose(fileID);
        % End function
        return
      end

      %
      % Enable the next row
      %
      buttonFirst = string(app.textTileRow);
      buttonLast = string((1:6)');
      buttonC = "C";
      button_ = "_";
      for l = 1:length(buttonLast)
        app.(buttonC+buttonFirst+button_+buttonLast(l)).Enable = "on";
      end
    end

    % Button pushed function: K0
    function K0ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "0";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K1
    function K1ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "1";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K2
    function K2ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "2";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K3
    function K3ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "3";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K4
    function K4ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "4";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K5
    function K5ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "5";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K6
    function K6ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "6";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K7
    function K7ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "7";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K8
    function K8ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "8";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: K9
    function K9ButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "9";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: KA
    function KAButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "A";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: KB
    function KBButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "B";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: KC
    function KCButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "C";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: KD
    function KDButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "D";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: KE
    function KEButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "E";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end

    % Button pushed function: KF
    function KFButtonPushed(app, event)
      % Get text tile position
      pos = app.textTilePosition;

      % Get list of tile names
      tileNames = app.textTileNames;
      
      % Update tile
      currentTile = tileNames(pos);
      app.(currentTile).Value = "F";

      % Update tile position
      if pos < 36
      if mod(pos,6) ~= 0
        app.textTilePosition = pos+1;
      end
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.ColordleLabel = uilabel(app.UIFigure);
      app.ColordleLabel.Text = 'Colordle';
      app.Help = uibutton(app.UIFigure, 'push');
      app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
      app.Help.Icon = fullfile(pathToMLAPP, 'help.svg');
      app.Help.Tooltip = {'Help'};
      app.Help.Text = '';
      app.C1_1 = uieditfield(app.UIFigure, 'text');
      app.C1_2 = uieditfield(app.UIFigure, 'text');
      app.C1_3 = uieditfield(app.UIFigure, 'text');
      app.C1_4 = uieditfield(app.UIFigure, 'text');
      app.C1_5 = uieditfield(app.UIFigure, 'text');
      app.C1_6 = uieditfield(app.UIFigure, 'text');
      app.C2_1 = uieditfield(app.UIFigure, 'text');
      app.C2_2 = uieditfield(app.UIFigure, 'text');
      app.C2_3 = uieditfield(app.UIFigure, 'text');
      app.C2_4 = uieditfield(app.UIFigure, 'text');
      app.C2_5 = uieditfield(app.UIFigure, 'text');
      app.C2_6 = uieditfield(app.UIFigure, 'text');
      app.C3_1 = uieditfield(app.UIFigure, 'text');
      app.C3_2 = uieditfield(app.UIFigure, 'text');
      app.C3_3 = uieditfield(app.UIFigure, 'text');
      app.C3_4 = uieditfield(app.UIFigure, 'text');
      app.C3_5 = uieditfield(app.UIFigure, 'text');
      app.C3_6 = uieditfield(app.UIFigure, 'text');
      app.C4_1 = uieditfield(app.UIFigure, 'text');
      app.C4_2 = uieditfield(app.UIFigure, 'text');
      app.C4_3 = uieditfield(app.UIFigure, 'text');
      app.C4_4 = uieditfield(app.UIFigure, 'text');
      app.C4_5 = uieditfield(app.UIFigure, 'text');
      app.C4_6 = uieditfield(app.UIFigure, 'text');
      app.C5_1 = uieditfield(app.UIFigure, 'text');
      app.C5_2 = uieditfield(app.UIFigure, 'text');
      app.C5_3 = uieditfield(app.UIFigure, 'text');
      app.C5_4 = uieditfield(app.UIFigure, 'text');
      app.C5_5 = uieditfield(app.UIFigure, 'text');
      app.C5_6 = uieditfield(app.UIFigure, 'text');
      app.C6_1 = uieditfield(app.UIFigure, 'text');
      app.C6_2 = uieditfield(app.UIFigure, 'text');
      app.C6_3 = uieditfield(app.UIFigure, 'text');
      app.C6_4 = uieditfield(app.UIFigure, 'text');
      app.C6_5 = uieditfield(app.UIFigure, 'text');
      app.C6_6 = uieditfield(app.UIFigure, 'text');
      app.K0 = uibutton(app.UIFigure, 'push');
      app.K0.ButtonPushedFcn = createCallbackFcn(app, @K0ButtonPushed, true);
      app.K0.Text = '0';
      app.K1 = uibutton(app.UIFigure, 'push');
      app.K1.ButtonPushedFcn = createCallbackFcn(app, @K1ButtonPushed, true);
      app.K1.Text = '1';
      app.K2 = uibutton(app.UIFigure, 'push');
      app.K2.ButtonPushedFcn = createCallbackFcn(app, @K2ButtonPushed, true);
      app.K2.Text = '2';
      app.K3 = uibutton(app.UIFigure, 'push');
      app.K3.ButtonPushedFcn = createCallbackFcn(app, @K3ButtonPushed, true);
      app.K3.Text = '3';
      app.K4 = uibutton(app.UIFigure, 'push');
      app.K4.ButtonPushedFcn = createCallbackFcn(app, @K4ButtonPushed, true);
      app.K4.Text = '4';
      app.K5 = uibutton(app.UIFigure, 'push');
      app.K5.ButtonPushedFcn = createCallbackFcn(app, @K5ButtonPushed, true);
      app.K5.Text = '5';
      app.K6 = uibutton(app.UIFigure, 'push');
      app.K6.ButtonPushedFcn = createCallbackFcn(app, @K6ButtonPushed, true);
      app.K6.Text = '6';
      app.K7 = uibutton(app.UIFigure, 'push');
      app.K7.ButtonPushedFcn = createCallbackFcn(app, @K7ButtonPushed, true);
      app.K7.Text = '7';
      app.K8 = uibutton(app.UIFigure, 'push');
      app.K8.ButtonPushedFcn = createCallbackFcn(app, @K8ButtonPushed, true);
      app.K8.Text = '8';
      app.K9 = uibutton(app.UIFigure, 'push');
      app.K9.ButtonPushedFcn = createCallbackFcn(app, @K9ButtonPushed, true);
      app.K9.Text = '9';
      app.KA = uibutton(app.UIFigure, 'push');
      app.KA.ButtonPushedFcn = createCallbackFcn(app, @KAButtonPushed, true);
      app.KA.Text = 'A';
      app.KB = uibutton(app.UIFigure, 'push');
      app.KB.ButtonPushedFcn = createCallbackFcn(app, @KBButtonPushed, true);
      app.KB.Text = 'B';
      app.KC = uibutton(app.UIFigure, 'push');
      app.KC.ButtonPushedFcn = createCallbackFcn(app, @KCButtonPushed, true);
      app.KC.Text = 'C';
      app.KD = uibutton(app.UIFigure, 'push');
      app.KD.ButtonPushedFcn = createCallbackFcn(app, @KDButtonPushed, true);
      app.KD.Text = 'D';
      app.KE = uibutton(app.UIFigure, 'push');
      app.KE.ButtonPushedFcn = createCallbackFcn(app, @KEButtonPushed, true);
      app.KE.Text = 'E';
      app.KF = uibutton(app.UIFigure, 'push');
      app.KF.ButtonPushedFcn = createCallbackFcn(app, @KFButtonPushed, true);
      app.KF.Text = 'F';
      app.Enter = uibutton(app.UIFigure, 'push');
      app.Enter.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
      app.Enter.Text = 'ENTER';
      app.Delete = uibutton(app.UIFigure, 'push');
      app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
      app.Delete.Text = '←';
      app.TheHexCode = uilabel(app.UIFigure);
      app.Version010Label = uilabel(app.UIFigure);
      app.Version010Label.Text = 'Version 0.1.0';
      app.ColorSample = uibutton(app.UIFigure, 'push');
      app.ColorSample.Tooltip = {'Try to guess the hex code for this color.'};
      app.ColorSample.Text = '';
      app.GuessthehexadecimalcodeforLabel = uilabel(app.UIFigure);
      app.GuessthehexadecimalcodeforLabel.Text = '| Guess the hexadecimal code for:';
      app.NewGame = uibutton(app.UIFigure, 'push');
      app.NewGame.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
      app.NewGame.Icon = fullfile(pathToMLAPP, 'reload.svg');
      app.NewGame.Tooltip = {'New Game'};
      app.NewGame.Text = '';
      app.Stats = uibutton(app.UIFigure, 'push');
      app.Stats.ButtonPushedFcn = createCallbackFcn(app, @StatsButtonPushed, true);
      app.Stats.Icon = fullfile(pathToMLAPP, 'stats.svg');
      app.Stats.Tooltip = {'Stats'};
      app.Stats.Text = '';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = colordle

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end